classdef pieceAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisorBieleUIFigure       matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        downloadPointsOrgImage   matlab.ui.container.Menu
        downloadPointsSVGSystem  matlab.ui.container.Menu
        Toolbar                  matlab.ui.container.Toolbar
        cursorTool               matlab.ui.container.toolbar.ToggleTool
        bBoxTool                 matlab.ui.container.toolbar.ToggleTool
        deleteOutliersTool       matlab.ui.container.toolbar.ToggleTool
        appLayout                matlab.ui.container.GridLayout
        settingsLayout           matlab.ui.container.GridLayout
        resultsGroup             matlab.ui.container.TabGroup
        results                  matlab.ui.container.Tab
        canvas                   matlab.ui.control.UIAxes
        configTabs               matlab.ui.container.TabGroup
        loadFiles                matlab.ui.container.Tab
        loadFilesLayout          matlab.ui.container.GridLayout
        buttonsPipelinePanel     matlab.ui.container.Panel
        buttonsPipelineLayout    matlab.ui.container.GridLayout
        calculateErrorButton     matlab.ui.control.Button
        startProcessing          matlab.ui.control.Button
        loadImageButton          matlab.ui.control.Button
        previewImage             matlab.ui.control.Image
        loadSVGButton            matlab.ui.control.Button
        previewSVG               matlab.ui.control.Image
    end

 
    properties (Access = private)
        stateApp;
        feedbackManager;

        canvasWrapper;
        previewImageWrapper;
        previewSVGWrapper;
        resultsConsoleWrapper;
        confParamsTab;
        
        toolsController;
        imageController;
        svgController;
        filterPipeController;
        errorPipeController;
        downloadFilesController;

        modelImage;
        modelSVG;
        configParams struct;
    end
    
    methods (Access = private)
        
        function colectParams(app)
            app.configParams.subpixel.threshold_Ph1 = app.confParamsTab.getThreshold_Ph1();
            app.configParams.subpixel.smoothIters_Ph1 = app.confParamsTab.getSmoothIters_Ph1();
            app.configParams.subpixel.scale = app.confParamsTab.getScale();
            app.configParams.subpixel.margin = app.confParamsTab.getMargin();

            app.configParams.subpixel.threshold_Ph2 = app.confParamsTab.getThreshold_Ph2();
            app.configParams.subpixel.smoothIters_Ph2 = app.confParamsTab.getSmoothIters_Ph2();

            app.configParams.DBSCAN.minPoints = app.confParamsTab.getEpsilon();
            app.configParams.DBSCAN.epsilon = app.confParamsTab.getEpsilon();

            app.configParams.error.pixelTomm = app.confParamsTab.getPixelTomm();
            app.configParams.error.tolerance = app.confParamsTab.getTolerance();
        end

        
        function resetImageContext(app)
        % resetImageContext() Completely resets all state, models, and UI elements 
        % related to the current image. 
        
            % --- Clean BBoxes ---
            if ~isempty(app.modelImage)
                app.modelImage.clearBBoxes();
            end
        
            % --- Clean results console ---
            if ~isempty(app.resultsConsoleWrapper)
                app.resultsConsoleWrapper.clearTabs();
            end
        
            % --- Clean canvas ---
            if ~isempty(app.canvas)
                app.canvasWrapper.cleanCanvas();
            end
        end


        function improveFileTabAppearance(app)
        % improveFileTabAppearance() Enhances the visual design of the "Carga de archivos" tab.
        %
        %   This function assumes that the UI components already exist in App Designer,
        %   including the tab, panels, and buttons. It applies consistent styling and
        %   prioritizes the main action buttons visually.
        %
        %   Inputs:
        %       - app: reference to the main App Designer application instance
        %
        %   Expected existing components (minimum):
        %       app.TabFiles        -> uitab (Carga de archivos)
        %       app.LoadImageButton -> button
        %       app.LoadSVGButton   -> button
        %       app.StartProcessButton -> button
        %       app.CalculateErrorButton -> button
        %       app.ImagePreviewAxes  -> uiaxes (optional)
        %       app.SVGPreviewAxes    -> uiaxes (optional)
        
            % --- Define corporate colors (Biele-style) ---
            orangeMain = [0.78,0.39,0.23];  % industrial orange
            darkGray   = [0.25 0.25 0.25];
            lightGray  = [0.97 0.97 0.97];
            accentBlue = [0.20 0.35 0.65];
        
            % --- General tab style ---
            app.loadFiles.BackgroundColor = lightGray;
        
            % --- File load buttons (secondary importance) ---
            commonSecondaryStyle = { ...
                'BackgroundColor', [0.94 0.94 0.94], ...
                'FontName', 'Segoe UI', ...
                'FontSize', 11, ...
                'FontWeight', 'normal', ...
                'FontColor', darkGray, ...
                'Tooltip', 'Cargar archivo desde el disco'};
        
            set(app.loadImageButton, commonSecondaryStyle{:});
            app.loadImageButton.IconAlignment = 'left';
        
            set(app.loadSVGButton, commonSecondaryStyle{:});
            app.loadSVGButton.IconAlignment = 'left';
        
            % --- Main action buttons (high importance) ---
            commonPrimaryStyle = { ...
                'BackgroundColor', orangeMain, ...
                'FontName', 'Segoe UI', ...
                'FontSize', 13, ...
                'FontWeight', 'bold', ...
                'FontColor', [1 1 1]};
        
            set(app.startProcessing, commonPrimaryStyle{:});
            app.startProcessing.Tooltip = 'Iniciar el procesamiento de la imagen';
        
            set(app.calculateErrorButton, commonPrimaryStyle{:});
            app.calculateErrorButton.Tooltip = 'Calcula el error geométrico respecto al SVG';
            drawnow;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % State App
            app.stateApp = models.AppState(app.cursorTool);
            app.feedbackManager = viewWrapper.FeedbackManager(app.VisorBieleUIFigure);

            % Appereance GUI
            app.improveFileTabAppearance()

            % Additions GUI
            app.confParamsTab = viewWrapper.TabParams(app.configTabs, app.feedbackManager);
            
            % Wrapper components
            app.canvasWrapper = viewWrapper.Canvas(app.canvas);
            app.previewImageWrapper = viewWrapper.PreviewFile(app.previewImage);
            app.previewSVGWrapper = viewWrapper.PreviewFile(app.previewSVG);
            app.resultsConsoleWrapper = viewWrapper.results.Console(app.resultsGroup);

            % Create models
            app.modelImage = models.Image();
            app.modelSVG   = models.SVG();

            % Create controllers
            app.toolsController = controllers.ToolsController(app.stateApp, ...
                app.modelImage, ...
                app.canvasWrapper, ...
                app.cursorTool, ...
                app.bBoxTool, ...
                app.deleteOutliersTool, ...
                app.feedbackManager);
            app.imageController = controllers.ImageController(app.stateApp, ...
                app.modelImage, ...
                app.previewImageWrapper, ...
                app.canvasWrapper, ...
                app.resultsConsoleWrapper, ...
                app.feedbackManager);
            app.svgController = controllers.SVGController(app.stateApp, ...
                app.modelSVG, ...
                app.previewSVGWrapper, ...
                app.canvasWrapper, ...
                app.feedbackManager);
            app.filterPipeController = controllers.FilterPipeController(app.stateApp, ...
                app.modelImage, ...
                app.canvasWrapper, ...
                app.resultsConsoleWrapper, ...
                app.feedbackManager);
            app.errorPipeController = controllers.ErrorPipeController(app.stateApp, ...
                app.modelImage, ...
                app.modelSVG, ...
                app.canvasWrapper, ...
                app.resultsConsoleWrapper, ...
                app.feedbackManager);
            app.downloadFilesController = controllers.DownloadFilesController(app.stateApp, ...
                app.resultsConsoleWrapper, ...
                app.modelImage, ...
                app.feedbackManager);
            
            % Pipe params
            app.configParams = struct( ...
                'subpixel', struct( ...
                    'threshold_Ph1',   app.confParamsTab.getThreshold_Ph1(), ...
                    'smoothIters_Ph1', app.confParamsTab.getSmoothIters_Ph1(), ...
                    'scale',           app.confParamsTab.getScale(), ...
                    'margin',          app.confParamsTab.getMargin(), ...
                    'threshold_Ph2',   app.confParamsTab.getThreshold_Ph2(), ...
                    'smoothIters_Ph2', app.confParamsTab.getSmoothIters_Ph2() ...
                ), ...
                'DBSCAN', struct( ...
                    'epsilon',    app.confParamsTab.getEpsilon(), ...
                    'minPoints',  app.confParamsTab.getMinPoints() ...
                ), ...
                'error', struct( ...
                    'pixelTomm',  app.confParamsTab.getPixelTomm(), ...
                    'tolerance',  app.confParamsTab.getTolerance() ...
                ) ...
            );

        end

        % Button pushed function: loadImageButton
        function loadImageButtonPushed(app, event)
            app.resetImageContext();

            % Allowed files
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;', ...
                'Imágenes (*.png, *.jpg, *.jpeg)'}, ...
                'Selecciona una imagen');

            % Not selected file
            if isequal(file, 0)
                return;
            end
            
            app.imageController.loadImageFromDialog(path, file);
        end

        % Image clicked function: previewImage
        function previewImageClicked(app, event)
            app.imageController.previewImageOnCanva();
        end

        % Button pushed function: loadSVGButton
        function loadSVGButtonPushed(app, event)
            % Allowed files
            [file, path] = uigetfile({'*.svg', 'Archivos SVG (*.svg)'}, ...
                                                 'Selecciona un archivo SVG');
            % Not selected file
            if isequal(file, 0)
                return;
            end
            
            app.svgController.loadSVGFromDialog(path, file);
        end

        % Image clicked function: previewSVG
        function previewSVGClicked(app, event)
            app.svgController.previewSVGOnCanva();
        end

        % On callback: cursorTool
        function cursorToolOn(app, event)
            app.stateApp.setActiveTool(app.cursorTool);
            controllers.ToolsController.resetEvents(app.canvas);
        end

        % Off callback: cursorTool
        function cursorToolOff(app, event)
            if app.stateApp.getActiveTool() == app.cursorTool
                app.stateApp.forceReactivateTool();
            end
            controllers.ToolsController.resetEvents(app.canvas);
        end

        % On callback: bBoxTool
        function bBoxToolOn(app, event)
            app.stateApp.setActiveTool(app.bBoxTool);
            app.toolsController.bboxEvents(app.canvas);
        end

        % Off callback: bBoxTool
        function bBoxToolOff(app, event)
            app.stateApp.setActiveTool(app.cursorTool);
            controllers.ToolsController.resetEvents(app.canvas);
        end

        % On callback: deleteOutliersTool
        function deleteOutliersToolOn(app, event)
            app.stateApp.setActiveTool(app.deleteOutliersTool);
            app.toolsController.deleteOutliersEvents(app.canvas);
        end

        % Off callback: deleteOutliersTool
        function deleteOutliersToolOff(app, event)
            app.stateApp.setActiveTool(app.cursorTool);
            controllers.ToolsController.resetEvents(app.canvas);
        end

        % Button pushed function: startProcessing
        function startProcessingButtonPushed(app, event)
            app.colectParams();
            app.filterPipeController.filterPipeline(app.configParams);
        end

        % Button pushed function: calculateErrorButton
        function calculateErrorButtonPushed(app, event)
            app.colectParams();
            app.errorPipeController.errorPipeline(app.configParams);
        end

        % Menu selected function: downloadPointsOrgImage
        function downloadPointsOrgImageMenuSelected(app, event)
            app.downloadFilesController.downloadXLSXPointsOverImage();
        end

        % Menu selected function: downloadPointsSVGSystem
        function downloadPointsSVGSystemMenuSelected(app, event)
            app.downloadFilesController.downloadXLSXPointsOverSVG();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VisorBieleUIFigure = uifigure('Visible', 'off');
            colormap(app.VisorBieleUIFigure, 'white');
            app.VisorBieleUIFigure.Name = 'Visor Biele';
            app.VisorBieleUIFigure.Icon = fullfile(pathToMLAPP, 'picturesApp', 'logoBiele.png');
            app.Menu = uimenu(app.VisorBieleUIFigure);
            app.Menu.Text = 'Menu';
            app.downloadPointsOrgImage = uimenu(app.Menu);
            app.downloadPointsOrgImage.MenuSelectedFcn = createCallbackFcn(app, @downloadPointsOrgImageMenuSelected, true);
            app.downloadPointsOrgImage.Text = 'Descargar .xlsx puntos sobre la imagen original';
            app.downloadPointsSVGSystem = uimenu(app.Menu);
            app.downloadPointsSVGSystem.MenuSelectedFcn = createCallbackFcn(app, @downloadPointsSVGSystemMenuSelected, true);
            app.downloadPointsSVGSystem.Text = 'Descargar .xlsx puntos sobre el plano SVG';
            app.Toolbar = uitoolbar(app.VisorBieleUIFigure);
            app.cursorTool = uitoggletool(app.Toolbar);
            app.cursorTool.Tooltip = {'Cursor'};
            app.cursorTool.Icon = fullfile(pathToMLAPP, 'picturesApp', 'cursor.png');
            app.cursorTool.OffCallback = createCallbackFcn(app, @cursorToolOff, true);
            app.cursorTool.OnCallback = createCallbackFcn(app, @cursorToolOn, true);
            app.bBoxTool = uitoggletool(app.Toolbar);
            app.bBoxTool.Tooltip = {'Dibujar BoundingBox sobre la pieza'};
            app.bBoxTool.Icon = fullfile(pathToMLAPP, 'picturesApp', 'roi.png');
            app.bBoxTool.OffCallback = createCallbackFcn(app, @bBoxToolOff, true);
            app.bBoxTool.OnCallback = createCallbackFcn(app, @bBoxToolOn, true);
            app.deleteOutliersTool = uitoggletool(app.Toolbar);
            app.deleteOutliersTool.Tooltip = {'Eliminar bordes no deseados'};
            app.deleteOutliersTool.Icon = fullfile(pathToMLAPP, 'picturesApp', 'eraser.png');
            app.deleteOutliersTool.OffCallback = createCallbackFcn(app, @deleteOutliersToolOff, true);
            app.deleteOutliersTool.OnCallback = createCallbackFcn(app, @deleteOutliersToolOn, true);
            app.appLayout = uigridlayout(app.VisorBieleUIFigure);
            app.configTabs = uitabgroup(app.appLayout);
            app.loadFiles = uitab(app.configTabs);
            app.loadFiles.Title = 'Carga de archivos';
            app.loadFilesLayout = uigridlayout(app.loadFiles);
            app.previewSVG = uiimage(app.loadFilesLayout);
            app.previewSVG.ImageClickedFcn = createCallbackFcn(app, @previewSVGClicked, true);
            app.loadSVGButton = uibutton(app.loadFilesLayout, 'push');
            app.loadSVGButton.ButtonPushedFcn = createCallbackFcn(app, @loadSVGButtonPushed, true);
            app.loadSVGButton.Text = 'Cargar SVG';
            app.previewImage = uiimage(app.loadFilesLayout);
            app.previewImage.ImageClickedFcn = createCallbackFcn(app, @previewImageClicked, true);
            app.loadImageButton = uibutton(app.loadFilesLayout, 'push');
            app.loadImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImageButtonPushed, true);
            app.loadImageButton.Text = 'Cargar imagen';
            app.buttonsPipelinePanel = uipanel(app.loadFilesLayout);
            app.buttonsPipelineLayout = uigridlayout(app.buttonsPipelinePanel);
            app.startProcessing = uibutton(app.buttonsPipelineLayout, 'push');
            app.startProcessing.ButtonPushedFcn = createCallbackFcn(app, @startProcessingButtonPushed, true);
            app.startProcessing.Text = 'Iniciar procesamiento';
            app.calculateErrorButton = uibutton(app.buttonsPipelineLayout, 'push');
            app.calculateErrorButton.ButtonPushedFcn = createCallbackFcn(app, @calculateErrorButtonPushed, true);
            app.calculateErrorButton.Text = 'Calcular error';
            app.settingsLayout = uigridlayout(app.appLayout);
            app.canvas = uiaxes(app.settingsLayout);
            xlabel(app.canvas, 'X')
            ylabel(app.canvas, 'Y')
            zlabel(app.canvas, 'Z')
            colormap(app.canvas, 'gray')
            app.resultsGroup = uitabgroup(app.settingsLayout);
            app.results = uitab(app.resultsGroup);
            app.results.Title = 'Resultados';
            % Show the figure after all components are created
            app.VisorBieleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pieceAnalyzer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VisorBieleUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.VisorBieleUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisorBieleUIFigure)
        end
    end
end